classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        degLabel_3                  matlab.ui.control.Label
        SYS                         matlab.ui.control.EditField
        SYSEditFieldLabel           matlab.ui.control.Label
        groundtrackPanel            matlab.ui.container.Panel
        skyplotPanel                matlab.ui.container.Panel
        kmLabel                     matlab.ui.control.Label
        degLabel_2                  matlab.ui.control.Label
        degLabel                    matlab.ui.control.Label
        minelevation                matlab.ui.control.NumericEditField
        minelevationEditFieldLabel  matlab.ui.control.Label
        H_                          matlab.ui.control.NumericEditField
        HEditFieldLabel             matlab.ui.control.Label
        lat_                        matlab.ui.control.NumericEditField
        latEditFieldLabel           matlab.ui.control.Label
        lon_                        matlab.ui.control.NumericEditField
        lonEditFieldLabel           matlab.ui.control.Label
        final                       matlab.ui.control.Label
        initial                     matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        epoch_                      matlab.ui.control.EditField
        epochEditFieldLabel         matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        state = 0;
        nav
        eph
        initial_epoch
        final_epoch


        fac = pi/180;

        lon
        lat
        h

        c_epoch


        startTime
        stopTime
        sampleTime = 300;

        gx

        view
        ac
        gs
        SAT
        sc
        han
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            uiload
            app.nav = nav;
            % navigaion
            app.state = 1;

            % app.max_prn = size(app.nav.eph,1);

            % if (app.PRN.Value>app.max_prn)||(app.PRN.Value<=0)
            %     app.PRN.Value = 1;
            % end

            if (~strcmp(app.SYS.Value,"GPS"))&&(~strcmp(app.SYS.Value,"QZSS"))&&(~strcmp(app.SYS.Value,"BDS"))
                app.SYS.Value = "GPS";
            end

            switch app.SYS.Value
                case "GPS"
                    app.initial_epoch = string(datetime(app.nav.GPS.toc(1:3)));
                    app.final_epoch = string(datetime(app.nav.GPS.toc(1:3))+days(1));
                    

                case "QZSS"
                    app.initial_epoch = string(datetime(app.nav.QZSS.toc(1:3)));
                    app.final_epoch = string(datetime(app.nav.QZSS.toc(1:3))+days(1));
                    

                case "BDS"
                    app.initial_epoch = string(datetime(app.nav.BDS.toc(1:3)));
                    app.final_epoch = string(datetime(app.nav.BDS.toc(1:3))+days(1));
                    

            end


            % app.initial_epoch = string(datetime(app.nav.eph{1}(1,2:4)));
            % app.final_epoch = string(datetime(app.nav.eph{1}(1,2:4)) + days(1));
            app.initial.Text = app.initial_epoch;
            app.final.Text = app.final_epoch;

            app.startTime = datetime(app.initial_epoch);
            app.stopTime = datetime(app.final_epoch); 


            app.sc = satelliteScenario(app.startTime, app.stopTime, app.sampleTime);

            app.epoch_.Value = app.initial_epoch;
            app.Slider.Limits = [0, 24*3600];

            app.Slider.Value = 24*3600;

            c_epoch = datetime(app.initial_epoch) + seconds(app.Slider.Value);
            app.epoch_.Value = string(c_epoch);


            %%

            fac = app.fac;

            c_epoch = datetime(app.epoch_.Value);

            i_epoch = datetime(app.initial_epoch);

            dt = i_epoch:seconds(app.sampleTime):c_epoch;


            lon = app.lon_.Value;
            lat = app.lat_.Value;
            H = app.H_.Value;


            % prn = app.PRN.Value;


            for i = 1:length(dt)

                [y,M,d] = ymd(dt(i));
                [h m s] = hms(dt(i));
                epoch = [y M d h m s];
                sat(i,1:6) = sat_nav_pos(app.nav, epoch, app.SYS.Value)';


            end


            [y,M,d] = ymd(c_epoch);
            [h m s] = hms(c_epoch);
            epoch = [y M d h m s];
            sat(i+1,1:6) = sat_nav_pos(app.nav, epoch, app.SYS.Value)';

            wgs84 = wgs84Ellipsoid('kilometer');

            [sat_lat, sat_lon, sat_h] = ecef2geodetic(wgs84,sat(:,1), sat(:,2), sat(:,3));
            sat_geo = [sat_lat, sat_lon, sat_h];


            EL_AZ = ecef2el_az(sat(:,1:3), [lat, lon, H], app.minelevation.Value);


            % pax = polaraxes(app.skyplotPanel);
            app.gx = geoaxes(app.groundtrackPanel);


            if ~isempty(EL_AZ(1,1))
                skyplot(app.skyplotPanel, EL_AZ(:,2)', EL_AZ(:,1),"MarkerSizeData",10);
            end
            geoplot(app.gx,sat_geo(:,1), sat_geo(:,2), '.');


            %% 사용

            [y,M,d] = ymd(dt(1));
            [h m s] = hms(dt(1));
            epoch = [y M d h m s];

            k = get_orbit_element_at_t(app.nav, epoch, app.SYS.Value);

            %%


            app.gs = groundStation(app.sc,lat,lon,"Altitude",H*10^3,"MinElevationAngle",app.minelevation.Value,"Name",'ground station');

            app.SAT = satellite(app.sc, k(1)*10^3, k(2), k(3), k(4), k(5), k(6) , "Name",app.SYS.Value);


            % app.SAT = satellite(app.sc, position, velocity, "CoordinateFrame","ecef","Name",['PRN ',num2str(prn)]);


            app.ac = access(app.SAT, app.gs);


            groundTrack(app.SAT);
            app.view = satelliteScenarioViewer(app.sc,'CameraReferenceFrame','Inertial','CurrentTime',c_epoch);
            campos(app.view,lat,lon,50000000);


        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            if app.state==1


            switch app.SYS.Value
                case "GPS"
                    app.initial_epoch = string(datetime(app.nav.GPS.toc(1:3)));
                    app.final_epoch = string(datetime(app.nav.GPS.toc(1:3))+days(1));
                    

                case "QZSS"
                    app.initial_epoch = string(datetime(app.nav.QZSS.toc(1:3)));
                    app.final_epoch = string(datetime(app.nav.QZSS.toc(1:3))+days(1));
                    

                case "BDS"
                    app.initial_epoch = string(datetime(app.nav.BDS.toc(1:3)));
                    app.final_epoch = string(datetime(app.nav.BDS.toc(1:3))+days(1));
                    

            end


            app.initial.Text = app.initial_epoch;
            app.final.Text = app.final_epoch;

            app.startTime = datetime(app.initial_epoch);
            app.stopTime = datetime(app.final_epoch); 


            % app.sc = satelliteScenario(app.startTime, app.stopTime, app.sampleTime);
            app.sc.StartTime = app.startTime;
            app.sc.StopTime = app.stopTime;


                c_epoch = datetime(app.initial_epoch) + seconds(app.Slider.Value);
                app.epoch_.Value = string(c_epoch);


                fac = app.fac;

                c_epoch = datetime(app.epoch_.Value);

                i_epoch = datetime(app.initial_epoch);

                dt = i_epoch:seconds(app.sampleTime):c_epoch;


                lon = app.lon_.Value;
                lat = app.lat_.Value;
                H = app.H_.Value;


                % prn = app.PRN.Value;


                for i = 1:length(dt)

                    [y,M,d] = ymd(dt(i));
                    [h m s] = hms(dt(i));
                    epoch = [y M d h m s];
                    sat(i,1:6) = sat_nav_pos(app.nav, epoch, app.SYS.Value)';


                end


                [y,M,d] = ymd(c_epoch);
                [h m s] = hms(c_epoch);
                epoch = [y M d h m s];
                sat(i+1,1:6) = sat_nav_pos(app.nav ,epoch, app.SYS.Value)';

                wgs84 = wgs84Ellipsoid('kilometer');

                [sat_lat, sat_lon, sat_h] = ecef2geodetic(wgs84,sat(:,1), sat(:,2), sat(:,3));
                sat_geo = [sat_lat, sat_lon, sat_h];


                EL_AZ = ecef2el_az(sat(:,1:3), [lat, lon, H], app.minelevation.Value);


                % pax = polaraxes(app.skyplotPanel);
                % gx = geoaxes(app.groundtrackPanel);


                if ~isempty(EL_AZ(1,1))
                    skyplot(app.skyplotPanel, EL_AZ(:,2)', EL_AZ(:,1),"MarkerSizeData",10);
                end
                geoplot(app.gx,sat_geo(:,1), sat_geo(:,2), '.');


                %% 사용

                [y,M,d] = ymd(dt(1));
                [h m s] = hms(dt(1));
                epoch = [y M d h m s];

                k = get_orbit_element_at_t(app.nav, epoch, app.SYS.Value);
                %%
                hide(app.gs)
                hide(app.SAT)
                hide(app.ac)

                app.gs = [];
                app.SAT = [];
                app.ac = [];


                app.sc.Viewers.CurrentTime = c_epoch;
                app.gs = groundStation(app.sc,lat,lon,"Altitude",H*10^3,"MinElevationAngle",app.minelevation.Value,"Name",'ground station');

                app.SAT = satellite(app.sc, k(1)*10^3, k(2), k(3), k(4), k(5), k(6) , "Name",app.SYS.Value);

                % app.SAT = satellite(app.sc, position, velocity, "CoordinateFrame","ecef","Name",['PRN ',num2str(prn)]);
                app.ac = access(app.SAT, app.gs);


                groundTrack(app.SAT);


                campos(app.view,lat,lon,50000000);
            end


        end

        % Value changed function: minelevation
        function minelevationValueChanged(app, event)
            value = app.minelevation.Value;
            if (value<0)||(value>90)
                app.minelevation.Value = 10;
            end

        end

        % Value changed function: lon_
        function lon_ValueChanged(app, event)
            value = app.lon_.Value;
            if value <0
                app.lon_.Value = mod(value,-360) + 360;
            else
                app.lon_.Value = mod(value, 360);
            end

        end

        % Value changed function: lat_
        function lat_ValueChanged(app, event)
            value = app.lat_.Value;
            if value >90
                app.lat_.Value = 90;
            elseif value<-90
                app.lat_.Value = -90;
            end

        end

        % Value changed function: SYS
        function SYSValueChanged(app, event)
            value = app.SYS.Value;
            if app.state == 1
                if (~strcmp(value,"GPS"))&&(~strcmp(value,"QZSS"))&&(~strcmp(value,"BDS"))
                    app.SYS.Value = "GPS";
                end

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.epochEditFieldLabel = uilabel(app.UIFigure);
            app.epochEditFieldLabel.Text = 'epoch';
            app.epoch_ = uieditfield(app.UIFigure, 'text');
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.initial = uilabel(app.UIFigure);
            app.initial.Text = '0';
            app.final = uilabel(app.UIFigure);
            app.final.Text = '0';
            app.lonEditFieldLabel = uilabel(app.UIFigure);
            app.lonEditFieldLabel.Text = 'lon';
            app.lon_ = uieditfield(app.UIFigure, 'numeric');
            app.lon_.ValueChangedFcn = createCallbackFcn(app, @lon_ValueChanged, true);
            app.latEditFieldLabel = uilabel(app.UIFigure);
            app.latEditFieldLabel.Text = 'lat';
            app.lat_ = uieditfield(app.UIFigure, 'numeric');
            app.lat_.ValueChangedFcn = createCallbackFcn(app, @lat_ValueChanged, true);
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.H_ = uieditfield(app.UIFigure, 'numeric');
            app.minelevationEditFieldLabel = uilabel(app.UIFigure);
            app.minelevationEditFieldLabel.Text = 'min elevation';
            app.minelevation = uieditfield(app.UIFigure, 'numeric');
            app.minelevation.ValueChangedFcn = createCallbackFcn(app, @minelevationValueChanged, true);
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.UIFigure);
            app.degLabel_2.Text = 'deg';
            app.kmLabel = uilabel(app.UIFigure);
            app.kmLabel.Text = 'km';
            app.skyplotPanel = uipanel(app.UIFigure);
            app.skyplotPanel.Title = 'sky plot';
            app.groundtrackPanel = uipanel(app.UIFigure);
            app.groundtrackPanel.Title = 'ground track';
            app.SYSEditFieldLabel = uilabel(app.UIFigure);
            app.SYSEditFieldLabel.Text = 'SYS';
            app.SYS = uieditfield(app.UIFigure, 'text');
            app.SYS.ValueChangedFcn = createCallbackFcn(app, @SYSValueChanged, true);
            app.degLabel_3 = uilabel(app.UIFigure);
            app.degLabel_3.Text = 'deg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end